classdef GameOfLife < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        InitializeButton  matlab.ui.control.Button
        NextCycleButton   matlab.ui.control.Button
        Image             matlab.ui.control.Image
        StartButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        game % game of life object
    end
    
    methods (Access = private)
        
        function printBoard(app)
            image = repmat(imresize(app.game.board, app.Image.Position(3:4), 'nearest'), 1, 1, 3);
            app.Image.ImageSource = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if (app.StartButton.Text == "Start")
                app.StartButton.Text = "Stop";
            else
                app.StartButton.Text = "Start";
            end
            while app.StartButton.Text == "Stop"
                app.game.nextCycle(10);
                app.printBoard();
                pause(0.05);
            end
        end

        % Button pushed function: NextCycleButton
        function NextCycleButtonPushed(app, event)
            app.game.nextCycle();
            app.printBoard()
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.game = Life(50, 50);
            app.game.fillRand();
            app.printBoard();
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            disp(app.Image.Position);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.NextCycleButton = uibutton(app.UIFigure, 'push');
            app.NextCycleButton.ButtonPushedFcn = createCallbackFcn(app, @NextCycleButtonPushed, true);
            app.NextCycleButton.Text = 'Next Cycle';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GameOfLife

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end